classdef colorphase < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ColorphaseUIFigure             matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        Menu_2                         matlab.ui.container.Menu
        Menu_4                         matlab.ui.container.Menu
        Menu_3                         matlab.ui.container.Menu
        Menu_5                         matlab.ui.container.Menu
        Menu_7                         matlab.ui.container.Menu
        Menu_6                         matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        D2                             matlab.ui.container.Tab
        Panel                          matlab.ui.container.Panel
        ModePanel                      matlab.ui.container.ButtonGroup
        Mode1Butten                    matlab.ui.control.ToggleButton
        Mode2Butten                    matlab.ui.control.ToggleButton
        WavelengthPanel                matlab.ui.container.Panel
        Lambda1Value                   matlab.ui.control.NumericEditField
        Lambda1ChooseButten            matlab.ui.control.StateButton
        Lambda2ChooseButten            matlab.ui.control.StateButton
        Lambda3ChooseButten            matlab.ui.control.StateButton
        Lambda2Value                   matlab.ui.control.NumericEditField
        Lambda3Value                   matlab.ui.control.NumericEditField
        ImprovingMethodsPanel          matlab.ui.container.Panel
        RandomPhaseButten              matlab.ui.control.StateButton
        SphericalPhaseButten           matlab.ui.control.StateButton
        DoublePhaseButten              matlab.ui.control.StateButton
        ParameterPanel                 matlab.ui.container.Panel
        iter_number                    matlab.ui.control.NumericEditField
        distance                       matlab.ui.control.NumericEditField
        IterationLabel                 matlab.ui.control.Label
        ImagingDistanceLabel           matlab.ui.control.Label
        focal_length                   matlab.ui.control.NumericEditField
        SphericalFocalLengthLabel      matlab.ui.control.Label
        CalculateButten                matlab.ui.control.Button
        ImportedImagePanel             matlab.ui.container.Panel
        RawImage                       matlab.ui.control.Image
        FourKindsImagePanel            matlab.ui.container.Panel
        RedChannel                     matlab.ui.control.Image
        GreenChannel                   matlab.ui.control.Image
        BlueChannel                    matlab.ui.control.Image
        Grayscale                      matlab.ui.control.Image
        ResultPanel                    matlab.ui.container.Panel
        DiffractionResultImage         matlab.ui.control.Image
        Panel_3                        matlab.ui.container.Panel
        PhaseOnlyHolograph             matlab.ui.control.Image
        Button                         matlab.ui.control.Button
        Button_2                       matlab.ui.control.Button
        SavePhaseOnlyImgButten         matlab.ui.control.Button
        Lamp_1                         matlab.ui.control.Lamp
        ColorDifferenceAnalysisButten  matlab.ui.control.Button
        NoiseAnalysisButten            matlab.ui.control.Button
        Lamp_2                         matlab.ui.control.Lamp
        Lamp_3                         matlab.ui.control.Lamp
        Panel_2                        matlab.ui.container.Panel
        ToningResult                   matlab.ui.control.Image
        Panel_4                        matlab.ui.container.Panel
        Image4                         matlab.ui.control.Image
        Button_3                       matlab.ui.control.Button
        Button_4                       matlab.ui.control.Button
        red_slider                     matlab.ui.control.Slider
        green_slider                   matlab.ui.control.Slider
        ToningButten                   matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        blue_slider                    matlab.ui.control.Slider
        Label_3                        matlab.ui.control.Label
        D3                             matlab.ui.container.Tab
        Panel_5                        matlab.ui.container.Panel
        ModePanel_2                    matlab.ui.container.ButtonGroup
        Mode1Butten_2                  matlab.ui.control.ToggleButton
        Mode2Butten_2                  matlab.ui.control.ToggleButton
        WavelengthPanel_2              matlab.ui.container.Panel
        Lambda1Value_2                 matlab.ui.control.NumericEditField
        Lambda1ChooseButten_2          matlab.ui.control.StateButton
        Lambda2ChooseButten_2          matlab.ui.control.StateButton
        Lambda3ChooseButten_2          matlab.ui.control.StateButton
        Lambda2Value_2                 matlab.ui.control.NumericEditField
        Lambda3Value_2                 matlab.ui.control.NumericEditField
        ParameterPanel_2               matlab.ui.container.Panel
        iter_value                     matlab.ui.control.NumericEditField
        dist_value                     matlab.ui.control.NumericEditField
        iter_choose                    matlab.ui.control.Label
        dist_vhoose                    matlab.ui.control.Label
        CalculateButten_2              matlab.ui.control.Button
        RotatePanel                    matlab.ui.container.Panel
        x_rotval                       matlab.ui.control.NumericEditField
        x_choose                       matlab.ui.control.StateButton
        y_choose                       matlab.ui.control.StateButton
        z_choose                       matlab.ui.control.StateButton
        y_rotval                       matlab.ui.control.NumericEditField
        z_value                        matlab.ui.control.NumericEditField
        ImportedImagePanel_2           matlab.ui.container.Panel
        origin_img                     matlab.ui.control.Image
        ResultPanel_2                  matlab.ui.container.Panel
        reconstruction_img             matlab.ui.control.Image
        save_reconstruction_btn        matlab.ui.control.Button
        Panel_6                        matlab.ui.container.Panel
        holo_img                       matlab.ui.control.Image
        save_pog_btn                   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: Menu_2
        function Menu_2Selected(app, event)
            global XRGB;
            [nom,chemin]=uigetfile(['*.*'],'调入模拟物体图像');%[ file, path ]
            if [nom,chemin]               
                [img,~] = imread([chemin,nom]); 
                img=imresize(img,[1920,1920]);
                XRGB = img;
                app.RawImage.ImageSource = img;% image imported
                imgR=img;imgR(:,:,2:3)=0;
                imgG=img;imgG(:,:,[1,3])=0;
                imgB=img;imgB(:,:,1:2)=0;
                imggray=rgb2gray(img);
                imgGray(:,:,1)=imggray;imgGray(:,:,2)=imggray;imgGray(:,:,3)=imggray;
                app.RedChannel.ImageSource = imgR; % Red channel
                app.GreenChannel.ImageSource = imgG; % Green channel 
                app.BlueChannel.ImageSource = imgB; % Blue channel
                app.Grayscale.ImageSource = imgGray; % Grayscale
                
                
                % three lamps at lower right corner
                app.Lamp_1.Color=[1 0 0];
                app.Lamp_2.Color=[0.94 0.94 0.94];
                app.Lamp_3.Color=[0.94 0.94 0.94];
            end
        end

        % Button pushed function: Button_4
        function Button_12Pushed(app, event)
            global XRGB;
            global pic1;global pic2;
            % LambdaChoose 
            wavelength{1}=app.Lambda1Value.Value;
            wavelength{2}=app.Lambda2Value.Value;
            wavelength{3}=app.Lambda3Value.Value;
            
%             dist=app.distance.Value;
%             iter_num=app.iter_number.Value;
%             f=app.focal_length.Value;
            %几种光
            LambdaChoose{1}=app.Lambda1ChooseButten.Value;
            LambdaChoose{2}=app.Lambda2ChooseButten.Value;
            LambdaChoose{3}=app.Lambda3ChooseButten.Value;
            
            % choose mode
            % mode1: spacedividing
            % mode2: superposition
            mode1=app.Mode1Butten.Value;


            % slider factor
            k1=app.red_slider.Value/50;
            k2=app.green_slider.Value/50;
            k3=app.blue_slider.Value/50;
            channels=LambdaChoose{1}+LambdaChoose{2}+LambdaChoose{3}; % number of color channels
            
            mat1=0.7*ones(1080,1920/channels);   % matrix of all 1s, adjust to 0.7
            mat0=zeros(1080,1920/channels);  % matrix of all 0s

            [tempm,tempn,~]=size(XRGB);
            
            % palette
            if channels>1 
                XR = XRGB(:,:,1);XG = XRGB(:,:,2);XB = XRGB(:,:,3);
            end
            if channels==1 
                if size(XRGB,3) == 3 
                    X00 = rgb2gray(XRGB); 
                else 
                    X00 = XRGB; 
                end 
            end
            
            Xtemp=zeros(tempm,tempn);
            if channels==3 
                    pic1=cat(3,k1.*XR,k2.*XG,k3.*XB);     
                    pic2=[cat(3,mat1,mat0,mat0),cat(3,mat0,mat1,mat0),cat(3,mat0,mat0,mat1)];
            end
            if channels==2
                if ~wavelength{1}
                        img=cat(3,Xtemp,k2.*XG,XB);
                        pic2=[cat(3,mat0,mat1,mat0),cat(3,mat0,mat0,mat1)];
                end
                if ~wavelength{2}
                        img=cat(3,k1.*XR,Xtemp,XB);
                        pic2=[cat(3,mat1,mat0,mat0),cat(3,mat0,mat0,mat1)];
                end
                if ~wavelength{3}
                        img=cat(3,k1.*XR,k2.*XG,Xtemp);
                        pic2=[cat(3,mat1,mat0,mat0),cat(3,mat0,mat1,mat0)];
                end
                pic1=img;
            end
            if channels==1
                if LambdaChoose{1}
                    img=cat(3,k1.*X00,Xtemp,Xtemp);
                    pic2=cat(3,0.6.*mat1,mat0,mat0);
                end
                 if LambdaChoose{2}
                    img=cat(3,Xtemp,k2.*X00,Xtemp);
                    pic2=cat(3,mat0,0.6.*mat1,mat0);
                 end
                 if LambdaChoose{3}
                    img=cat(3,Xtemp,Xtemp,X00);
                    pic2=cat(3,mat0,mat0,0.6.*mat1);
                 end
                pic1=img;
            end
            pic1=imresize(pic1,[1000,1000]);
            app.ToningResult.ImageSource = pic1;
            
            if(~mode1)
                pic2='../datas/1.png';
            end
            app.Image4.ImageSource = pic2;
            app.Lamp_1.Color=[0.94 0.94 0.94];
            app.Lamp_2.Color=[1 1 0];
            app.Lamp_3.Color=[0.94 0.94 0.94];
        end

        % Button pushed function: CalculateButten
        function CalculateButtenPushed(app, event)
            global XRGB;global pic3;global pic4;
            Button_12Pushed(app, event);%刷新          

            wavelength{1}=app.Lambda1Value.Value;
            wavelength{2}=app.Lambda2Value.Value;
            wavelength{3}=app.Lambda3Value.Value;
            dist=app.distance.Value;
            iter_num=app.iter_number.Value;
            f=app.focal_length.Value;
            %几种光
            LambdaChoose{1}=app.Lambda1ChooseButten.Value;
            LambdaChoose{2}=app.Lambda2ChooseButten.Value;
            LambdaChoose{3}=app.Lambda3ChooseButten.Value;
            % choose mode
            mode1=app.Mode1Butten.Value;
            % add improving methods
            random_phase=app.RandomPhaseButten.Value;
            spherical_phase=app.SphericalPhaseButten.Value;
            biphase=app.DoublePhaseButten.Value;
            
            if mode1
                [pic4,pic3]=calculate_mode1(LambdaChoose,wavelength,XRGB,dist,f,random_phase,iter_num,spherical_phase,biphase);       
            else
                [pic4,pic3]=calculate_mode2(XRGB,wavelength,dist,random_phase,biphase);
            end
            % ImageSource should have 3 channels
            pic4_color(:,:,1)=pic4;pic4_color(:,:,2)=pic4;pic4_color(:,:,3)=pic4;
            app.DiffractionResultImage.ImageSource = pic3;
            app.PhaseOnlyHolograph.ImageSource = pic4_color;    
            app.Lamp_1.Color=[0.94 0.94 0.94];
            app.Lamp_2.Color=[0.94 0.94 0.94];
            app.Lamp_3.Color=[0 1 0];
           
        end

        % Button pushed function: Button
        function Button_13Pushed(app, event)
            global pic3;
            figure,imshow(pic3);
        end

        % Button pushed function: Button_2
        function Button_14Pushed(app, event)
            global pic4;
            figure,imshow(pic4);
        end

        % Button pushed function: SavePhaseOnlyImgButten
        function SavePhaseOnlyImgButtenPushed(app, event)
            %保存相息图
            global pic4;
            [fname,pname,~] = uiputfile({'*.bmp','BMP (*.bmp)';'*.jpg','JPEG (*.jpg)';'*.tif','TIF (*.tif)'}, '保存相息图');
            imwrite(pic4, [pname,fname]);
        end

        % Menu selected function: Menu_3
        function Menu_3Selected(app, event)
            %保存相息图
            global pic4;
            [fname,pname,~] = uiputfile({'*.bmp','BMP (*.bmp)';'*.jpg','JPEG (*.jpg)';'*.tif','TIF (*.tif)'}, '保存相息图');
            imwrite(pic4, [pname,fname]);
        end

        % Button pushed function: Button_3
        function Button_10Pushed(app, event)
            global pic1;
            figure,imshow(pic1);
        end

        % Callback function
        function Button_11Pushed(app, event)
            global pic1;
            [fname,pname,~] = uiputfile({'*.bmp','BMP (*.bmp)';'*.jpg','JPEG (*.jpg)';'*.tif','TIF (*.tif)'}, '保存预览图');
            imwrite(pic1, [pname,fname]);
        end

        % Menu selected function: Menu_5
        function Menu_5Selected(app, event)
            global pic1;
            [fname,pname,~] = uiputfile({'*.bmp','BMP (*.bmp)';'*.jpg','JPEG (*.jpg)';'*.tif','TIF (*.tif)'}, '保存预览图');
            imwrite(pic1, [pname,fname]);
        end

        % Menu selected function: Menu_7
        function Menu_7Selected(app, event)
            global pic3;
            [fname,pname,~] = uiputfile({'*.bmp','BMP (*.bmp)';'*.jpg','JPEG (*.jpg)';'*.tif','TIF (*.tif)'}, '保存模拟图');
            imwrite(pic3, [pname,fname]);
        end

        % Button pushed function: ToningButten
        function ToningButtenPushed(app, event)
            global XRGB;global pic1;global pic2;
            %------------------传值--------------------%
            %几种光
            LambdaChoose{1}=app.Lambda1ChooseButten.Value;
            LambdaChoose{2}=app.Lambda2ChooseButten.Value;
            LambdaChoose{3}=app.Lambda3ChooseButten.Value;           
            %-----------------传值结束------------------%
            k1=app.red_slider.Value/50;k2=app.green_slider.Value/50;k3=app.blue_slider.Value/50;
            channels=LambdaChoose{1}+LambdaChoose{2}+LambdaChoose{3};
            [tempm,tempn,~]=size(XRGB);
            if channels>1 
                XR = XRGB(:,:,1);XG = XRGB(:,:,2);XB = XRGB(:,:,3);
            end
            if channels==1 
            if size(XRGB,3) == 3 
                X00 = rgb2gray(XRGB); 
            else 
                X00 = XRGB; 
            end 
            end
            
            Xtemp=zeros(tempm,tempn);
            if channels==3
                    pic1=cat(3,k1.*XR,k2.*XG,k3.*XB);                   
            end
            if channels==2
                if ~wavelength{1}
                        img=cat(3,Xtemp,k2.*XG,k3.*XB);
                end
                if ~wavelength{2}
                        img=cat(3,k1.*XR,Xtemp,k3.*XB);
                end
                if ~wavelength{3}
                        img=cat(3,k1.*XR,k2.*XG,Xtemp);
                end
                pic1=img;
            end
            if channels==1
                if LambdaChoose{1}
                    img=cat(3,k1.*X00,Xtemp,Xtemp);
                end
                 if LambdaChoose{2}
                    img=cat(3,Xtemp,k2.*X00,Xtemp);
                 end
                 if LambdaChoose{3}
                    img=cat(3,Xtemp,Xtemp,k3.*X00);
                 end
                pic1=img;
            end
            pic1=imresize(pic1,[1000,1000]);
            app.ToningResult.ImageSource = pic1;
            
            app.Lamp_1.Color=[0.94 0.94 0.94];
            app.Lamp_2.Color=[1 1 0];
            app.Lamp_3.Color=[0.94 0.94 0.94];
        end

        % Button pushed function: ColorDifferenceAnalysisButten
        function ColorDifferenceAnalysisButtenPushed(app, event)
            global pic1;global pic3;
            anlysis_CA(pic1,pic3);
        end

        % Button pushed function: NoiseAnalysisButten
        function NoiseAnalysisButtenPushed(app, event)
            global pic1;global pic3;
            anlysis_SNR(pic1,pic3);
        end

        % Image clicked function: DiffractionResultImage
        function DiffractionResultImageClicked(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ColorphaseUIFigure = uifigure('Visible', 'off');
            app.ColorphaseUIFigure.Name = 'Color phase';
            app.Menu = uimenu(app.ColorphaseUIFigure);
            app.Menu.Text = '文件';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Menu_2.Text = '导入';
            app.Menu_4 = uimenu(app.Menu);
            app.Menu_4.Text = '保存';
            app.Menu_3 = uimenu(app.Menu_4);
            app.Menu_3.MenuSelectedFcn = createCallbackFcn(app, @Menu_3Selected, true);
            app.Menu_3.Text = '保存相位全息图';
            app.Menu_5 = uimenu(app.Menu_4);
            app.Menu_5.MenuSelectedFcn = createCallbackFcn(app, @Menu_5Selected, true);
            app.Menu_5.Text = '保存预览图';
            app.Menu_7 = uimenu(app.Menu_4);
            app.Menu_7.MenuSelectedFcn = createCallbackFcn(app, @Menu_7Selected, true);
            app.Menu_7.Text = '保存模拟图';
            app.Menu_6 = uimenu(app.ColorphaseUIFigure);
            app.Menu_6.Text = '帮助';
            app.TabGroup = uitabgroup(app.ColorphaseUIFigure);
            app.D2 = uitab(app.TabGroup);
            app.D2.Title = '2D全息';
            app.Panel = uipanel(app.D2);
            app.ModePanel = uibuttongroup(app.Panel);
            app.ModePanel.Title = 'Mode';
            app.Mode1Butten = uitogglebutton(app.ModePanel);
            app.Mode1Butten.Text = '空间划分';
            app.Mode1Butten.Value = true;
            app.Mode2Butten = uitogglebutton(app.ModePanel);
            app.Mode2Butten.Text = '空间叠加';
            app.WavelengthPanel = uipanel(app.Panel);
            app.WavelengthPanel.Title = 'Lambda（nm）';
            app.Lambda1Value = uieditfield(app.WavelengthPanel, 'numeric');
            app.Lambda1Value.Value = 635;
            app.Lambda1ChooseButten = uibutton(app.WavelengthPanel, 'state');
            app.Lambda1ChooseButten.Text = 'λ1';
            app.Lambda1ChooseButten.Value = true;
            app.Lambda2ChooseButten = uibutton(app.WavelengthPanel, 'state');
            app.Lambda2ChooseButten.Text = 'λ2';
            app.Lambda3ChooseButten = uibutton(app.WavelengthPanel, 'state');
            app.Lambda3ChooseButten.Text = 'λ3';
            app.Lambda2Value = uieditfield(app.WavelengthPanel, 'numeric');
            app.Lambda2Value.Value = 520;
            app.Lambda3Value = uieditfield(app.WavelengthPanel, 'numeric');
            app.Lambda3Value.Value = 450;
            app.ImprovingMethodsPanel = uipanel(app.Panel);
            app.ImprovingMethodsPanel.Title = 'Improving Methods';
            app.RandomPhaseButten = uibutton(app.ImprovingMethodsPanel, 'state');
            app.RandomPhaseButten.Text = '随机';
            app.SphericalPhaseButten = uibutton(app.ImprovingMethodsPanel, 'state');
            app.SphericalPhaseButten.Text = '球面';
            app.SphericalPhaseButten.Value = true;
            app.DoublePhaseButten = uibutton(app.ImprovingMethodsPanel, 'state');
            app.DoublePhaseButten.Text = '双相位';
            app.ParameterPanel = uipanel(app.Panel);
            app.ParameterPanel.Title = 'Initial Settings';
            app.iter_number = uieditfield(app.ParameterPanel, 'numeric');
            app.distance = uieditfield(app.ParameterPanel, 'numeric');
            app.distance.Value = 1600;
            app.IterationLabel = uilabel(app.ParameterPanel);
            app.IterationLabel.Text = '迭代次数';
            app.ImagingDistanceLabel = uilabel(app.ParameterPanel);
            app.ImagingDistanceLabel.Text = '成像距离';
            app.focal_length = uieditfield(app.ParameterPanel, 'numeric');
            app.focal_length.Value = 200;
            app.SphericalFocalLengthLabel = uilabel(app.ParameterPanel);
            app.SphericalFocalLengthLabel.Text = '球面焦距';
            app.CalculateButten = uibutton(app.Panel, 'push');
            app.CalculateButten.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtenPushed, true);
            app.CalculateButten.Text = '计算';
            app.ImportedImagePanel = uipanel(app.D2);
            app.RawImage = uiimage(app.ImportedImagePanel);
            app.FourKindsImagePanel = uipanel(app.ImportedImagePanel);
            app.RedChannel = uiimage(app.FourKindsImagePanel);
            app.GreenChannel = uiimage(app.FourKindsImagePanel);
            app.BlueChannel = uiimage(app.FourKindsImagePanel);
            app.Grayscale = uiimage(app.FourKindsImagePanel);
            app.ResultPanel = uipanel(app.D2);
            app.DiffractionResultImage = uiimage(app.ResultPanel);
            app.DiffractionResultImage.ImageClickedFcn = createCallbackFcn(app, @DiffractionResultImageClicked, true);
            app.Panel_3 = uipanel(app.ResultPanel);
            app.PhaseOnlyHolograph = uiimage(app.Panel_3);
            app.Button = uibutton(app.ResultPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button.Text = '还原图放大';
            app.Button_2 = uibutton(app.ResultPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_2.Text = '相息图放大';
            app.SavePhaseOnlyImgButten = uibutton(app.ResultPanel, 'push');
            app.SavePhaseOnlyImgButten.ButtonPushedFcn = createCallbackFcn(app, @SavePhaseOnlyImgButtenPushed, true);
            app.SavePhaseOnlyImgButten.Text = '保存相息图';
            app.Lamp_1 = uilamp(app.ResultPanel);
            app.ColorDifferenceAnalysisButten = uibutton(app.ResultPanel, 'push');
            app.ColorDifferenceAnalysisButten.ButtonPushedFcn = createCallbackFcn(app, @ColorDifferenceAnalysisButtenPushed, true);
            app.ColorDifferenceAnalysisButten.Text = '色差分析';
            app.NoiseAnalysisButten = uibutton(app.ResultPanel, 'push');
            app.NoiseAnalysisButten.ButtonPushedFcn = createCallbackFcn(app, @NoiseAnalysisButtenPushed, true);
            app.NoiseAnalysisButten.Text = '噪声分析';
            app.Lamp_2 = uilamp(app.ResultPanel);
            app.Lamp_3 = uilamp(app.ResultPanel);
            app.Panel_2 = uipanel(app.D2);
            app.ToningResult = uiimage(app.Panel_2);
            app.Panel_4 = uipanel(app.Panel_2);
            app.Image4 = uiimage(app.Panel_4);
            app.Button_3 = uibutton(app.Panel_2, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_3.Text = '预览图放大';
            app.Button_4 = uibutton(app.Panel_2, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_4.Text = '预览';
            app.red_slider = uislider(app.Panel_2);
            app.red_slider.Value = 50;
            app.green_slider = uislider(app.Panel_2);
            app.green_slider.Value = 50;
            app.ToningButten = uibutton(app.Panel_2, 'push');
            app.ToningButten.ButtonPushedFcn = createCallbackFcn(app, @ToningButtenPushed, true);
            app.ToningButten.Text = '调色';
            app.Label = uilabel(app.Panel_2);
            app.Label.Text = '红';
            app.Label_2 = uilabel(app.Panel_2);
            app.Label_2.Text = '绿';
            app.blue_slider = uislider(app.Panel_2);
            app.blue_slider.Value = 50;
            app.Label_3 = uilabel(app.Panel_2);
            app.Label_3.Text = '蓝';
            app.D3 = uitab(app.TabGroup);
            app.D3.Title = '3D全息';
            app.Panel_5 = uipanel(app.D3);
            app.ModePanel_2 = uibuttongroup(app.Panel_5);
            app.ModePanel_2.Title = 'Mode';
            app.Mode1Butten_2 = uitogglebutton(app.ModePanel_2);
            app.Mode1Butten_2.Text = '非彩色';
            app.Mode2Butten_2 = uitogglebutton(app.ModePanel_2);
            app.Mode2Butten_2.Text = '彩色';
            app.WavelengthPanel_2 = uipanel(app.Panel_5);
            app.WavelengthPanel_2.Title = '波长 (nm)';
            app.Lambda1Value_2 = uieditfield(app.WavelengthPanel_2, 'numeric');
            app.Lambda1Value_2.Value = 635;
            app.Lambda1ChooseButten_2 = uibutton(app.WavelengthPanel_2, 'state');
            app.Lambda1ChooseButten_2.Text = 'λ1';
            app.Lambda1ChooseButten_2.Value = true;
            app.Lambda2ChooseButten_2 = uibutton(app.WavelengthPanel_2, 'state');
            app.Lambda2ChooseButten_2.Text = 'λ2';
            app.Lambda3ChooseButten_2 = uibutton(app.WavelengthPanel_2, 'state');
            app.Lambda3ChooseButten_2.Text = 'λ3';
            app.Lambda2Value_2 = uieditfield(app.WavelengthPanel_2, 'numeric');
            app.Lambda2Value_2.Value = 520;
            app.Lambda3Value_2 = uieditfield(app.WavelengthPanel_2, 'numeric');
            app.Lambda3Value_2.Value = 450;
            app.ParameterPanel_2 = uipanel(app.Panel_5);
            app.ParameterPanel_2.Title = 'Initial Settings';
            app.iter_value = uieditfield(app.ParameterPanel_2, 'numeric');
            app.iter_value.Value = 20;
            app.dist_value = uieditfield(app.ParameterPanel_2, 'numeric');
            app.dist_value.Value = 1600;
            app.iter_choose = uilabel(app.ParameterPanel_2);
            app.iter_choose.Text = '迭代次数';
            app.dist_vhoose = uilabel(app.ParameterPanel_2);
            app.dist_vhoose.Text = '成像距离';
            app.CalculateButten_2 = uibutton(app.Panel_5, 'push');
            app.CalculateButten_2.Text = '计算';
            app.RotatePanel = uipanel(app.Panel_5);
            app.RotatePanel.Title = '旋转';
            app.x_rotval = uieditfield(app.RotatePanel, 'numeric');
            app.x_choose = uibutton(app.RotatePanel, 'state');
            app.x_choose.Text = 'x';
            app.x_choose.Value = true;
            app.y_choose = uibutton(app.RotatePanel, 'state');
            app.y_choose.Text = 'y';
            app.z_choose = uibutton(app.RotatePanel, 'state');
            app.z_choose.Text = 'z';
            app.y_rotval = uieditfield(app.RotatePanel, 'numeric');
            app.z_value = uieditfield(app.RotatePanel, 'numeric');
            app.ImportedImagePanel_2 = uipanel(app.D3);
            app.origin_img = uiimage(app.ImportedImagePanel_2);
            app.ResultPanel_2 = uipanel(app.D3);
            app.reconstruction_img = uiimage(app.ResultPanel_2);
            app.save_reconstruction_btn = uibutton(app.ResultPanel_2, 'push');
            app.save_reconstruction_btn.Text = '保存还原图';
            app.Panel_6 = uipanel(app.D3);
            app.holo_img = uiimage(app.Panel_6);
            app.save_pog_btn = uibutton(app.Panel_6, 'push');
            app.save_pog_btn.Text = '保存相息图';
            % Show the figure after all components are created
            app.ColorphaseUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = colorphase

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ColorphaseUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ColorphaseUIFigure)
        end
    end
end