classdef HelloHolo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HelloHoloUIFigure        matlab.ui.Figure
        Menu                     matlab.ui.container.Menu
        Menu_2                   matlab.ui.container.Menu
        Menu_4                   matlab.ui.container.Menu
        Menu_3                   matlab.ui.container.Menu
        Menu_5                   matlab.ui.container.Menu
        Menu_7                   matlab.ui.container.Menu
        Menu_6                   matlab.ui.container.Menu
        TabGroup                 matlab.ui.container.TabGroup
        D3                       matlab.ui.container.Tab
        Panel_5                  matlab.ui.container.Panel
        WavelengthPanel_2        matlab.ui.container.Panel
        Lambda1Value_2           matlab.ui.control.NumericEditField
        Lambda1ChooseButten_2    matlab.ui.control.StateButton
        Lambda2ChooseButten_2    matlab.ui.control.StateButton
        Lambda3ChooseButten_2    matlab.ui.control.StateButton
        Lambda2Value_2           matlab.ui.control.NumericEditField
        Lambda3Value_2           matlab.ui.control.NumericEditField
        ParameterPanel_2         matlab.ui.container.Panel
        iter_value               matlab.ui.control.NumericEditField
        dist_value               matlab.ui.control.NumericEditField
        iter_choose              matlab.ui.control.Label
        dist_choose              matlab.ui.control.Label
        depth_choose             matlab.ui.control.Label
        depth_value              matlab.ui.control.NumericEditField
        slice_value              matlab.ui.control.NumericEditField
        slice_choose             matlab.ui.control.Label
        m0                       matlab.ui.control.NumericEditField
        m0_choose                matlab.ui.control.Label
        RotatePanel              matlab.ui.container.Panel
        Reset                    matlab.ui.control.Button
        ySpinnerLabel            matlab.ui.control.Label
        ySpinner                 matlab.ui.control.Spinner
        zSpinnerLabel            matlab.ui.control.Label
        zSpinner                 matlab.ui.control.Spinner
        xSpinnerLabel            matlab.ui.control.Label
        xSpinner                 matlab.ui.control.Spinner
        GIFButton                matlab.ui.control.StateButton
        CalculateButten_2        matlab.ui.control.Button
        ParameterPanel_3         matlab.ui.container.Panel
        gif_xrot_choose          matlab.ui.control.Label
        gif_xrot                 matlab.ui.control.NumericEditField
        gif_yrot_choose          matlab.ui.control.Label
        gif_yrot                 matlab.ui.control.NumericEditField
        gif_zrot_choose          matlab.ui.control.Label
        gif_zrot                 matlab.ui.control.NumericEditField
        ImportedImagePanel_2     matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
        ResultPanel_2            matlab.ui.container.Panel
        reconstruction_img       matlab.ui.control.Image
        save_reconstruction_btn  matlab.ui.control.Button
        save_pog_btn             matlab.ui.control.Button
        holo_img                 matlab.ui.control.Image
        Button_5                 matlab.ui.control.Button
        Button_6                 matlab.ui.control.Button
        Button_7                 matlab.ui.control.StateButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, path)
            path="./bunny.txt";
            global m;
            global origin_m;
            m=load(path);
            origin_m=m;
%             pcshow(m);
            scatter3(app.UIAxes,m(:,1),m(:,2),m(:,3));
            app.UIAxes.View=[0,90];
        end

        % Menu selected function: Menu_2
        function Menu_2Selected(app, event)
            global m;
            global origin_m;
            [img_name,img_path]=uigetfile('*.txt;*.xyz;*.ply');

            objpath = [img_path,img_name];
            m=load(objpath);
            if(length(m)>100000)
                disp(length(m))
                m=msparse(m);

            origin_m=m;
            scatter3(app.UIAxes,m(:,1),m(:,2),m(:,3));
            app.UIAxes.View=[0,90];
              
            end

            
        end

        % Callback function
        function Button_12Pushed(app, event)

           
            % LambdaChoose 
            wavelength{1}=app.Lambda1Value.Value;
            wavelength{2}=app.Lambda2Value.Value;
            wavelength{3}=app.Lambda3Value.Value;
            
%             dist=app.distance.Value;
%             iter_num=app.iter_number.Value;
%             f=app.focal_length.Value;
            %几种光
            LambdaChoose{1}=app.Lambda1ChooseButten.Value;
            LambdaChoose{2}=app.Lambda2ChooseButten.Value;
            LambdaChoose{3}=app.Lambda3ChooseButten.Value;

        end

        % Callback function
        function CalculateButtenPushed(app, event)
            global XRGB;global pic3;global pic4;
            Button_12Pushed(app, event);%刷新          

            wavelength{1}=app.Lambda1Value.Value;
            wavelength{2}=app.Lambda2Value.Value;
            wavelength{3}=app.Lambda3Value.Value;
            dist=app.distance.Value;
            iter_num=app.iter_number.Value;
            f=app.focal_length.Value;
            %几种光
            LambdaChoose{1}=app.Lambda1ChooseButten.Value;
            LambdaChoose{2}=app.Lambda2ChooseButten.Value;
            LambdaChoose{3}=app.Lambda3ChooseButten.Value;

            % add improving methods
            random_phase=app.RandomPhaseButten.Value;

            % ImageSource should have 3 channels
            pic4_color(:,:,1)=pic4;pic4_color(:,:,2)=pic4;pic4_color(:,:,3)=pic4;
            app.DiffractionResultImage.ImageSource = pic3;
            app.PhaseOnlyHolograph.ImageSource = pic4_color;    
            app.Lamp_1.Color=[0.94 0.94 0.94];
            app.Lamp_2.Color=[0.94 0.94 0.94];
            app.Lamp_3.Color=[0 1 0];
           
        end

        % Callback function
        function Button_13Pushed(app, event)
            global pic3;
            figure,imshow(pic3);
        end

        % Callback function
        function Button_14Pushed(app, event)
            global pic4;
            figure,imshow(pic4);
        end

        % Callback function
        function SavePhaseOnlyImgButtenPushed(app, event)
            %保存相息图
            global pic4;
            [fname,pname,~] = uiputfile({'*.bmp','BMP (*.bmp)';'*.jpg','JPEG (*.jpg)';'*.tif','TIF (*.tif)'}, '保存相息图');
            imwrite(pic4, [pname,fname]);
        end

        % Menu selected function: Menu_3
        function Menu_3Selected(app, event)
            %保存相息图
            global pic4;
            [fname,pname,~] = uiputfile({'*.bmp','BMP (*.bmp)';'*.jpg','JPEG (*.jpg)';'*.tif','TIF (*.tif)'}, '保存相息图');
            imwrite(pic4, [pname,fname]);
        end

        % Callback function
        function Button_10Pushed(app, event)
            global pic1;
            figure,imshow(pic1);
        end

        % Callback function
        function Button_11Pushed(app, event)
            global pic1;
            [fname,pname,~] = uiputfile({'*.bmp','BMP (*.bmp)';'*.jpg','JPEG (*.jpg)';'*.tif','TIF (*.tif)'}, '保存预览图');
            imwrite(pic1, [pname,fname]);
        end

        % Menu selected function: Menu_5
        function Menu_5Selected(app, event)
            global pic1;
            [fname,pname,~] = uiputfile({'*.bmp','BMP (*.bmp)';'*.jpg','JPEG (*.jpg)';'*.tif','TIF (*.tif)'}, '保存预览图');
            imwrite(pic1, [pname,fname]);
        end

        % Menu selected function: Menu_7
        function Menu_7Selected(app, event)
            global pic3;
            [fname,pname,~] = uiputfile({'*.bmp','BMP (*.bmp)';'*.jpg','JPEG (*.jpg)';'*.tif','TIF (*.tif)'}, '保存模拟图');
            imwrite(pic3, [pname,fname]);
        end

        % Callback function
        function ToningButtenPushed(app, event)

        end

        % Callback function
        function ColorDifferenceAnalysisButtenPushed(app, event)

        end

        % Callback function
        function NoiseAnalysisButtenPushed(app, event)

        end

        % Button pushed function: CalculateButten_2
        function CalculateButten_2ButtonPushed(app, event)
            global pog;
            global rcst_img;
            global m;
            
            if app.Lambda1ChooseButten_2.Value==1
                lambda=app.Lambda1Value_2.Value*1e-6;
            end
            if app.Lambda2ChooseButten_2.Value==1
                lambda=app.Lambda2Value_2.Value*1e-6;
            end
            if app.Lambda3ChooseButten_2.Value==1
                lambda=app.Lambda3Value_2.Value*1e-6;
            end
            
            
            z0=app.dist_value.Value;
            depth=app.depth_value.Value;
            slices=app.slice_value.Value;
            iter_num=app.iter_value.Value;
     
            [pog,rcst_img]=calculate_3dgray(m,lambda,z0,depth,slices,iter_num,app.m0.Value);
            app.holo_img.ImageSource=pog;
            app.reconstruction_img.ImageSource=rcst_img;
        end

        % Button pushed function: save_reconstruction_btn
        function save_reconstruction_btnButtonPushed(app, event)
            global rcst_img;
            [fname,pname,~] = uiputfile({'*.bmp','BMP (*.bmp)';'*.jpg','JPEG (*.jpg)';'*.tif','TIF (*.tif)'}, '模拟还原图');
            imwrite(rcst_img, [pname,fname]);
        end

        % Button pushed function: save_pog_btn
        function save_pog_btnButtonPushed(app, event)
            global pog;
            [fname,pname,~] = uiputfile({'*.bmp','BMP (*.bmp)';'*.jpg','JPEG (*.jpg)';'*.tif','TIF (*.tif)'}, '相息图');
            imwrite(pog(:,:,1), [pname,fname]);
        end

        % Callback function
        function x_rotvalValueChanged(app, event)
            xangle = app.x_rotval.Value;
            global m;
            m=rotx(m,xangle);
        end

        % Callback function
        function y_rotvalValueChanged(app, event)
            yangle = app.y_rotval.Value;
            global m;
            m=roty(m,yangle);
        end

        % Callback function
        function z_rotvalValueChanged(app, event)
            zangle = app.z_rotval.Value;
            global m;
            m=rotz(m,zangle);
            
        end

        % Callback function
        function xSpinnerValueChanged(app, event)
            
        end

        % Value changed function: ySpinner
        function ySpinnerValueChanged(app, event)

            
        end

        % Value changed function: zSpinner
        function zSpinnerValueChanged(app, event)
            
        end

        % Value changed function: xSpinner
        function xSpinnerValueChanged2(app, event)
   
        end

        % Button pushed function: Reset
        function ResetButtonPushed(app, event)
            global m;
            global origin_m;
            m=origin_m;
            app.xSpinner.Value=0;
            app.ySpinner.Value=0;
            app.zSpinner.Value=0;
            scatter3(app.UIAxes,m(:,1),m(:,2),m(:,3));
            app.UIAxes.View=[0,90];
        end

        % Value changing function: xSpinner
        function xSpinnerValueChanging(app, event)
            previousval=app.xSpinner.Value;
            changingval = event.Value;
            xangle = (changingval-previousval)*pi/180;
            global m;
            m=rotx(m,xangle);
%             disp(app.UIAxes.View)
            scatter3(app.UIAxes,m(:,1),m(:,2),m(:,3));
%             disp(app.UIAxes.View)
            app.UIAxes.View=[0,90]; 
            
        end

        % Value changing function: ySpinner
        function ySpinnerValueChanging(app, event)
            previousval=app.ySpinner.Value;
            changingval = event.Value;
            yangle = (changingval-previousval)*pi/180;
            global m;
            m=roty(m,yangle);
            scatter3(app.UIAxes,m(:,1),m(:,2),m(:,3));
            app.UIAxes.View=[0,90];
        end

        % Value changing function: zSpinner
        function zSpinnerValueChanging(app, event)
            previousval=app.zSpinner.Value;
            changingval = event.Value;
            zangle = (changingval-previousval)*pi/180;
            global m;
            m=rotz(m,zangle);
            scatter3(app.UIAxes,m(:,1),m(:,2),m(:,3));
            app.UIAxes.View=[0,90];
            
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            global pog;
            imwrite(pog(:,:,1),['./pog','-iter',num2str(app.iter_value.Value),'.bmp']);

        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            global rcst_img;
            imwrite(rcst_img,['./rcst','-iter',num2str(app.iter_value.Value),'.bmp']);
            
        end

        % Menu selected function: Menu_6
        function Menu_6Selected(app, event)
            open('README.pdf');
        end

        % Value changed function: Button_7
        function Button_7ValueChanged(app, event)
            global pog;
            imshow(pog);
            
        end

        % Value changed function: GIFButton
        function GIFButtonValueChanged(app, event)
            if app.Lambda1ChooseButten_2.Value==1
                lambda=app.Lambda1Value_2.Value*1e-6;
            end
            if app.Lambda2ChooseButten_2.Value==1
                lambda=app.Lambda2Value_2.Value*1e-6;
            end
            if app.Lambda3ChooseButten_2.Value==1
                lambda=app.Lambda3Value_2.Value*1e-6;
            end
            z0=app.dist_value.Value;
            depth=app.depth_value.Value;
            slices=app.slice_value.Value;
            iter_num=app.iter_value.Value;
            
            global m;
            % default x->y->z
            for xangle=1:app.gif_xrot.Value
                [pog,rcst_img]=calculate_3dgray(rotx(m,xangle*pi/180),lambda,z0,depth,slices,iter_num,app.m0.Value);
                imwrite(pog,['./gif/pog-', num2str(xangle),'.bmp']);
                imwrite(rcst_img,['./gif/rcst-', num2str(xangle),'.bmp']);
            
            end
            for yangle=1:app.gif_yrot.Value
                [pog,rcst_img]=calculate_3dgray(roty(m,yangle*pi/180),lambda,z0,depth,slices,iter_num,app.m0.Value);
                imwrite(pog,['./gif/pog-', num2str(yangle),'.bmp']);
                imwrite(rcst_img,['./gif/rcst-', num2str(yangle),'.bmp']);
            
            end
            for zangle=1:app.gif_zrot.Value
                [pog,rcst_img]=calculate_3dgray(rotz(m,yangle*pi/180),lambda,z0,depth,slices,iter_num,app.m0.Value);
                imwrite(pog,['./gif/pog-', num2str(zangle),'.bmp']);
                imwrite(rcst_img,['./gif/rcst-', num2str(zangle),'.bmp']);
            
            end

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HelloHoloUIFigure = uifigure('Visible', 'off');
            app.HelloHoloUIFigure.Name = 'Color phase';
            app.Menu = uimenu(app.HelloHoloUIFigure);
            app.Menu.Text = '文件';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Menu_2.Text = '导入';
            app.Menu_4 = uimenu(app.Menu);
            app.Menu_4.Text = '保存';
            app.Menu_3 = uimenu(app.Menu_4);
            app.Menu_3.MenuSelectedFcn = createCallbackFcn(app, @Menu_3Selected, true);
            app.Menu_3.Text = '保存相位全息图';
            app.Menu_5 = uimenu(app.Menu_4);
            app.Menu_5.MenuSelectedFcn = createCallbackFcn(app, @Menu_5Selected, true);
            app.Menu_5.Text = '保存预览图';
            app.Menu_7 = uimenu(app.Menu_4);
            app.Menu_7.MenuSelectedFcn = createCallbackFcn(app, @Menu_7Selected, true);
            app.Menu_7.Text = '保存模拟图';
            app.Menu_6 = uimenu(app.HelloHoloUIFigure);
            app.Menu_6.MenuSelectedFcn = createCallbackFcn(app, @Menu_6Selected, true);
            app.Menu_6.Text = '帮助';
            app.TabGroup = uitabgroup(app.HelloHoloUIFigure);
            app.D3 = uitab(app.TabGroup);
            app.D3.Title = '3D全息';
            app.Panel_5 = uipanel(app.D3);
            app.WavelengthPanel_2 = uipanel(app.Panel_5);
            app.WavelengthPanel_2.Title = '波长 (nm)';
            app.Lambda1Value_2 = uieditfield(app.WavelengthPanel_2, 'numeric');
            app.Lambda1Value_2.Value = 635;
            app.Lambda1ChooseButten_2 = uibutton(app.WavelengthPanel_2, 'state');
            app.Lambda1ChooseButten_2.Text = 'λ1';
            app.Lambda2ChooseButten_2 = uibutton(app.WavelengthPanel_2, 'state');
            app.Lambda2ChooseButten_2.Text = 'λ2';
            app.Lambda2ChooseButten_2.Value = true;
            app.Lambda3ChooseButten_2 = uibutton(app.WavelengthPanel_2, 'state');
            app.Lambda3ChooseButten_2.Text = 'λ3';
            app.Lambda2Value_2 = uieditfield(app.WavelengthPanel_2, 'numeric');
            app.Lambda2Value_2.Value = 520;
            app.Lambda3Value_2 = uieditfield(app.WavelengthPanel_2, 'numeric');
            app.Lambda3Value_2.Value = 450;
            app.ParameterPanel_2 = uipanel(app.Panel_5);
            app.ParameterPanel_2.Title = '全息计算参数';
            app.iter_value = uieditfield(app.ParameterPanel_2, 'numeric');
            app.iter_value.Value = 1;
            app.dist_value = uieditfield(app.ParameterPanel_2, 'numeric');
            app.dist_value.Value = 800;
            app.iter_choose = uilabel(app.ParameterPanel_2);
            app.iter_choose.Text = '迭代次数';
            app.dist_choose = uilabel(app.ParameterPanel_2);
            app.dist_choose.Text = '成像距离';
            app.depth_choose = uilabel(app.ParameterPanel_2);
            app.depth_choose.Text = '物体深度';
            app.depth_value = uieditfield(app.ParameterPanel_2, 'numeric');
            app.depth_value.Value = 30;
            app.slice_value = uieditfield(app.ParameterPanel_2, 'numeric');
            app.slice_value.Value = 50;
            app.slice_choose = uilabel(app.ParameterPanel_2);
            app.slice_choose.Text = '切片层数';
            app.m0 = uieditfield(app.ParameterPanel_2, 'numeric');
            app.m0.Limits = [0 1];
            app.m0.Value = 0.5;
            app.m0_choose = uilabel(app.ParameterPanel_2);
            app.m0_choose.Text = '缩放因子';
            app.RotatePanel = uipanel(app.Panel_5);
            app.RotatePanel.Title = '旋转';
            app.Reset = uibutton(app.RotatePanel, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Reset.Text = '还原';
            app.ySpinnerLabel = uilabel(app.RotatePanel);
            app.ySpinnerLabel.Text = 'y';
            app.ySpinner = uispinner(app.RotatePanel);
            app.ySpinner.ValueChangingFcn = createCallbackFcn(app, @ySpinnerValueChanging, true);
            app.ySpinner.Limits = [-180 180];
            app.ySpinner.ValueChangedFcn = createCallbackFcn(app, @ySpinnerValueChanged, true);
            app.zSpinnerLabel = uilabel(app.RotatePanel);
            app.zSpinnerLabel.Text = 'z';
            app.zSpinner = uispinner(app.RotatePanel);
            app.zSpinner.ValueChangingFcn = createCallbackFcn(app, @zSpinnerValueChanging, true);
            app.zSpinner.Limits = [-180 180];
            app.zSpinner.ValueChangedFcn = createCallbackFcn(app, @zSpinnerValueChanged, true);
            app.xSpinnerLabel = uilabel(app.RotatePanel);
            app.xSpinnerLabel.Text = 'x';
            app.xSpinner = uispinner(app.RotatePanel);
            app.xSpinner.ValueChangingFcn = createCallbackFcn(app, @xSpinnerValueChanging, true);
            app.xSpinner.Limits = [-180 180];
            app.xSpinner.ValueChangedFcn = createCallbackFcn(app, @xSpinnerValueChanged2, true);
            app.GIFButton = uibutton(app.Panel_5, 'state');
            app.GIFButton.ValueChangedFcn = createCallbackFcn(app, @GIFButtonValueChanged, true);
            app.GIFButton.Text = '制作GIF';
            app.CalculateButten_2 = uibutton(app.Panel_5, 'push');
            app.CalculateButten_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButten_2ButtonPushed, true);
            app.CalculateButten_2.Text = '计算';
            app.ParameterPanel_3 = uipanel(app.Panel_5);
            app.ParameterPanel_3.Title = 'GIF制作参数';
            app.gif_xrot_choose = uilabel(app.ParameterPanel_3);
            app.gif_xrot_choose.Text = 'GIF-x旋转角度';
            app.gif_xrot = uieditfield(app.ParameterPanel_3, 'numeric');
            app.gif_xrot.Limits = [0 360];
            app.gif_xrot.ValueDisplayFormat = '%.0f';
            app.gif_xrot.Value = 60;
            app.gif_yrot_choose = uilabel(app.ParameterPanel_3);
            app.gif_yrot_choose.Text = 'GIF-y旋转角度';
            app.gif_yrot = uieditfield(app.ParameterPanel_3, 'numeric');
            app.gif_yrot.Limits = [0 360];
            app.gif_yrot.ValueDisplayFormat = '%.0f';
            app.gif_zrot_choose = uilabel(app.ParameterPanel_3);
            app.gif_zrot_choose.Text = 'GIF-z旋转角度';
            app.gif_zrot = uieditfield(app.ParameterPanel_3, 'numeric');
            app.gif_zrot.Limits = [0 360];
            app.gif_zrot.ValueDisplayFormat = '%.0f';
            app.ImportedImagePanel_2 = uipanel(app.D3);
            app.UIAxes = uiaxes(app.ImportedImagePanel_2);
            title(app.UIAxes, '3D-origin-display')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ResultPanel_2 = uipanel(app.D3);
            app.reconstruction_img = uiimage(app.ResultPanel_2);
            app.save_reconstruction_btn = uibutton(app.ResultPanel_2, 'push');
            app.save_reconstruction_btn.ButtonPushedFcn = createCallbackFcn(app, @save_reconstruction_btnButtonPushed, true);
            app.save_reconstruction_btn.Text = '保存还原图';
            app.save_pog_btn = uibutton(app.ResultPanel_2, 'push');
            app.save_pog_btn.ButtonPushedFcn = createCallbackFcn(app, @save_pog_btnButtonPushed, true);
            app.save_pog_btn.Text = '保存相息图';
            app.holo_img = uiimage(app.ResultPanel_2);
            app.Button_5 = uibutton(app.ResultPanel_2, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '快速保存';
            app.Button_6 = uibutton(app.ResultPanel_2, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '快速保存';
            app.Button_7 = uibutton(app.ResultPanel_2, 'state');
            app.Button_7.ValueChangedFcn = createCallbackFcn(app, @Button_7ValueChanged, true);
            app.Button_7.Text = '放大显示';
            % Show the figure after all components are created
            app.HelloHoloUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HelloHolo(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HelloHoloUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HelloHoloUIFigure)
        end
    end
end